classdef FactoryFailureExplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        Status                     matlab.ui.control.Lamp
        SelectUrgencyButtonGroup   matlab.ui.container.ButtonGroup
        HighButton                 matlab.ui.control.RadioButton
        MediumButton               matlab.ui.control.RadioButton
        LowButton                  matlab.ui.control.RadioButton
        SelectAllButtonUrgency     matlab.ui.control.RadioButton
        EnterDescriptionLabel      matlab.ui.control.Label
        RunButton                  matlab.ui.control.Button
        ResetButton                matlab.ui.control.Button
        SearchEditField            matlab.ui.control.EditField
        SelectCategoryButtonGroup  matlab.ui.container.ButtonGroup
        SoftwareFailureButton      matlab.ui.control.RadioButton
        MechanicalFailureButton    matlab.ui.control.RadioButton
        LeakButton                 matlab.ui.control.RadioButton
        ElectronicFailureButton    matlab.ui.control.RadioButton
        SelectAllButtonFailure     matlab.ui.control.RadioButton
        FileSelect                 matlab.ui.control.DropDown
        SelectdataLabel            matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        ResultsTable               matlab.ui.control.Table
        TabGroup                   matlab.ui.container.TabGroup
        VisualizedescriptionsTab   matlab.ui.container.Tab
        ExploretableTab            matlab.ui.container.Tab
        ExploreTable               matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        bag % This property will later contain a bag-of-words model from the Descriptions
        reports % Will contain the loaded data
        reportsFilt % Will contain the filtered data
    end
    
    methods (Access = private)
        
        function visualizeAndDisplay(app)
            % --- 1. Display table ---
            app.ExploreTable.Data = app.reportsFilt; % Assign the filtered data to the table in the app
            app.ExploreTable.Visible = "on"; % Make the table visible
            
            % --- 2. Visualize descriptions for filtered table with wordcloud            
            trimDescription(app) % Preprocess Descriptions            
            wordcloud(app.VisualizedescriptionsTab,app.bag); % Create wordcloud
            if ~isempty(app.VisualizedescriptionsTab.Children) 
                app.VisualizedescriptionsTab.Children.Visible = "on"; % Make the wordcloud visible
            end
    
            % --- 3. Calculate Cost variable for filtered table
            sumCost = sum(app.reportsFilt.Cost); % Sum up all Cost for filtered data
            percentCost = sum(app.reportsFilt.Cost) / sum(app.reports.Cost) * 100; % Calculate the percentage of total Cost
            percentNum  = height(app.reportsFilt) / height(app.reports) * 100; % Calculate the percentage of total Failures
            app.ResultsTable.Data = [sumCost percentCost percentNum]; % Assign the values to the Results table
        end

        function trimDescription(app)  
            % Preprocess descriptions
            doc = tokenizedDocument(app.reportsFilt.Description); % Creates a tokenized document
            if ~isempty(doc)
                cleanedDoc = addPartOfSpeechDetails(doc); % Adds part-of-speech tags to document
                cleanedDoc = removeStopWords(cleanedDoc); % Removes stop words, like "a", "to", and "the"
                cleanedDoc = normalizeWords(cleanedDoc,'Style','lemma'); % Recudes words to a root form
                cleanedDoc = erasePunctuation(cleanedDoc); % Deletes any punctuation in the document
                cleanedDoc = removeShortWords(cleanedDoc,2); % Removes short words of length 2 or shorter
                cleanedDoc = removeWords(cleanedDoc,app.SearchEditField.Value);
                app.bag = bagOfWords(cleanedDoc); % Creates a bag-of-words model from the cleanedDoc variable
            else
                app.bag = "NoData"; % Sets the bag property to "NoData" if the doc variable is empty
            end
        end
        
        function changeStatus(app,color,status)
            switch color
                case "red"
                    app.Status.Color = [0.85,0.33,0.10]; % Setting the status lamp to red                    
                case "green"
                    app.Status.Color = [0.39,0.83,0.07]; % Setting the status lamp to green                      
            end
            app.SelectUrgencyButtonGroup.Enable = status; % Enabling/Disabling the Category button group
            app.SelectCategoryButtonGroup.Enable = status; % Enabling/Disabling the Urgency button group
            app.SearchEditField.Enable = status; % Enabling/Disabling the Search
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Resetting the interface to initial values
            app.FileSelect.Value = "factoryReports.csv"; % Set the selected file
            app.SelectAllButtonFailure.Value = 1; % Set the Failure button to Select All
            app.SelectAllButtonUrgency.Value = 1; % Set the Urgency button to Select All
            app.SearchEditField.Value = ""; % Clear the Search field         
            app.ExploreTable.Visible = "off"; % Hide the table           
            app.ResultsTable.Data = []; % Clear the Results table
            if ~isempty(app.VisualizedescriptionsTab.Children)
                app.VisualizedescriptionsTab.Children.Visible = "off"; % Clear the wordcloud
            end
            % Loading data
            app.reports = importReportsApp(app.FileSelect.Value); % Loading data into reports property
            app.reportsFilt = app.reports; % Initializing the reportsFilt property
            app.bag = []; % Initialize bag property. Needed for searching later.

            % Setting the status lamp to green and enabling the filters
            changeStatus(app,"green","on");            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            changeStatus(app,"red","off");            
            startupFcn(app); % Resetting the interface to the initial values
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            changeStatus(app,"red", "off"); % Setting the status lamp to red and disable filters
            app.reportsFilt = app.reports; % Resetting the filtered table
            
            % Filter for selected failure Category
            failureFilt = app.SelectCategoryButtonGroup.SelectedObject; % Get the selected failure Category
            if failureFilt.Text ~= "Select all" 
                rowsToKeep = app.reportsFilt.Category == failureFilt.Text; % Identify table entries that satisfy the condition (Category)
                app.reportsFilt = app.reportsFilt(rowsToKeep,:); % Update reportsFilt to only keep the specified rows
            end

            % Filter for selected Urgency
            urgencyFilt = app.SelectUrgencyButtonGroup.SelectedObject; % Get the selected Urgency
            if urgencyFilt.Text ~= "Select all"
                rowsToKeep = app.reportsFilt.Urgency == urgencyFilt.Text; % Identify table entries that satisfy the condition (Urgency)
                app.reportsFilt = app.reportsFilt(rowsToKeep,:); % Update reportsFilt to only keep the specified rows
            end
            
            % Filter for entered Search term
            searchFilt = app.SearchEditField.Value; % Get the entered Search field term
            if ~isempty(searchFilt)
                rowsToKeep = contains(app.reportsFilt.Description,searchFilt); % Identify table entries that contain the search term
                app.reportsFilt = app.reportsFilt(rowsToKeep,:); % Update reportsFilt to only keep the specified rows
            end   

            % Visualize the descriptions, display the table, and update the Cost table 
            visualizeAndDisplay(app)
            changeStatus(app, "green", "on") % Set status lamp to green and enable filters
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {451, 451};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {242, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SelectdataLabel = uilabel(app.LeftPanel);
            app.SelectdataLabel.Text = 'Select data';
            app.FileSelect = uidropdown(app.LeftPanel);
            app.FileSelect.Items = {'factoryReports.csv'};
            app.FileSelect.Value = 'factoryReports.csv';
            app.SelectCategoryButtonGroup = uibuttongroup(app.LeftPanel);
            app.SelectCategoryButtonGroup.Title = 'Select Category ';
            app.SelectAllButtonFailure = uiradiobutton(app.SelectCategoryButtonGroup);
            app.SelectAllButtonFailure.Text = 'Select all';
            app.SelectAllButtonFailure.Value = true;
            app.ElectronicFailureButton = uiradiobutton(app.SelectCategoryButtonGroup);
            app.ElectronicFailureButton.Text = 'Electronic Failure';
            app.LeakButton = uiradiobutton(app.SelectCategoryButtonGroup);
            app.LeakButton.Text = 'Leak';
            app.MechanicalFailureButton = uiradiobutton(app.SelectCategoryButtonGroup);
            app.MechanicalFailureButton.Text = 'Mechanical Failure';
            app.SoftwareFailureButton = uiradiobutton(app.SelectCategoryButtonGroup);
            app.SoftwareFailureButton.Text = 'Software Failure';
            app.SearchEditField = uieditfield(app.LeftPanel, 'text');
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.EnterDescriptionLabel = uilabel(app.LeftPanel);
            app.EnterDescriptionLabel.Text = '🔎Enter Description filter (optional)';
            app.SelectUrgencyButtonGroup = uibuttongroup(app.LeftPanel);
            app.SelectUrgencyButtonGroup.Title = 'Select Urgency';
            app.SelectAllButtonUrgency = uiradiobutton(app.SelectUrgencyButtonGroup);
            app.SelectAllButtonUrgency.Text = 'Select all';
            app.SelectAllButtonUrgency.Value = true;
            app.LowButton = uiradiobutton(app.SelectUrgencyButtonGroup);
            app.LowButton.Text = 'Low';
            app.MediumButton = uiradiobutton(app.SelectUrgencyButtonGroup);
            app.MediumButton.Text = 'Medium';
            app.HighButton = uiradiobutton(app.SelectUrgencyButtonGroup);
            app.HighButton.Text = 'High';
            app.Status = uilamp(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.VisualizedescriptionsTab = uitab(app.TabGroup);
            app.VisualizedescriptionsTab.Title = 'Visualize descriptions';
            app.ExploretableTab = uitab(app.TabGroup);
            app.ExploretableTab.Title = 'Explore table';
            app.ExploreTable = uitable(app.ExploretableTab);
            app.ExploreTable.ColumnName = {'Failure Description'; 'Category'; 'Urgency'; 'Resolution'; 'Cost'};
            app.ExploreTable.ColumnRearrangeable = 'on';
            app.ExploreTable.RowName = {};
            app.ExploreTable.ColumnSortable = true;
            app.ResultsTable = uitable(app.RightPanel);
            app.ResultsTable.ColumnName = {'Total Cost'; '% of Total Cost'; '% of Total Failures'};
            app.ResultsTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FactoryFailureExplorer

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end